classdef DialogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Label      matlab.ui.control.Label
        EditField  matlab.ui.control.NumericEditField
        Label_2    matlab.ui.control.Label
        DropDown   matlab.ui.control.DropDown
        Button     matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp   % 主子窗口对象
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, sz, c)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Update UI with input values
            app.EditField.Value = sz;
            app.DropDown.Value = c;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Call main app's public function
            updateplot(app.CallingApp, app.EditField.Value, app.DropDown.Value);
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.OptionsButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '样本量';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [2 1000];
            app.EditField.Value = 35;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '颜色图';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Parula', 'Jet', 'Winter', 'Cool'};
            app.DropDown.Value = 'Parula';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确定';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end